classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ModelListBoxLabel           matlab.ui.control.Label
        ModelListBox                matlab.ui.control.ListBox
        TESTButton                  matlab.ui.control.Button
        KListBoxLabel               matlab.ui.control.Label
        KListBox                    matlab.ui.control.ListBox
        DatasetListBoxLabel         matlab.ui.control.Label
        DatasetListBox              matlab.ui.control.ListBox
        DistanceMeasureLabel        matlab.ui.control.Label
        DistanceMeasureListBox      matlab.ui.control.ListBox
        ComponentCountListBoxLabel  matlab.ui.control.Label
        ComponentCountListBox       matlab.ui.control.ListBox
        ThresholdSpinnerLabel       matlab.ui.control.Label
        ThresholdSpinner            matlab.ui.control.Spinner
        UIAxes                      matlab.ui.control.UIAxes
        TextArea                    matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        counter = 1;
        counters=[];
        modelnames=[];
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TESTButton
        function TESTButtonPushed(app, event)
            dataset = app.DatasetListBox.Value;
            if dataset == "Yale"
                dataset = "CenteredYaleFaces-A";
            elseif dataset == "AT&T Faces"
                dataset = "attfaces"; 
            end
            
            modelName = app.ModelListBox.Value;
            
            if modelName == "EigenFace"
                modelName = "Eigen1";
            elseif modelName == "EigenFaceWO3"
                modelName = "EigenWO3"; 
            elseif modelName == "FisherFace"
                modelName = "FisherFace"; 
            end
            
            k = str2double(app.KListBox.Value);
 

            distanceModel = app.DistanceMeasureListBox.Value;
            if distanceModel == "Mahalanobis"
                distanceModel = "Mahalonobis";
            end   
            componentCount = str2double(app.ComponentCountListBox.Value);
            threshold = app.ThresholdSpinner.Value;

             [X,y,datasetName] = LoadDataset(dataset);
             [CorrectlyLabeled,missLabeled,ERRORPERCENT]=CrossValidation(X,y,modelName,distanceModel,k,threshold,componentCount)
%             
%             


             if distanceModel == "Euclidean"
                    distmodelshort="Euc.";
                else
                    distmodelshort="Mah.";
                end
                if modelName=="Eigen1"
                    modelshort="Eig";
                elseif modelName==  "EigenWO3"
                    modelshort="Eig-3";
                else
                    modelshort="Fisher";
                end
                modelname = sprintf("%s %s k=%d nComp. %d",modelshort,distmodelshort,k,componentCount);
                title(app.UIAxes,"Model VS Error")
                xlabel(app.UIAxes,"Models")
                ylabel(app.UIAxes,"Percent Error")
                ylim(app.UIAxes,[0 45])
                %ERRORPERCENT=randi(30);
                bar(app.UIAxes,app.counter,ERRORPERCENT);
                hold(app.UIAxes,"on");
                app.counters = [app.counters app.counter];
                app.modelnames=[app.modelnames modelname];
                xticks(app.UIAxes,app.counters);
                xticklabels(app.UIAxes,app.modelnames);
                xtickangle(app.UIAxes,70);
                
                text(app.UIAxes,app.counter,ERRORPERCENT,num2str(ERRORPERCENT),'vert','bottom','horiz','center'); 
                box(app.UIAxes,'off');

                app.counter =app.counter +1;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ModelListBoxLabel = uilabel(app.UIFigure);
            app.ModelListBoxLabel.Text = 'Model';
            app.ModelListBox = uilistbox(app.UIFigure);
            app.ModelListBox.Items = {'EigenFace', 'EigenFaceWO3', 'FisherFace'};
            app.ModelListBox.Value = 'EigenFace';
            app.TESTButton = uibutton(app.UIFigure, 'push');
            app.TESTButton.ButtonPushedFcn = createCallbackFcn(app, @TESTButtonPushed, true);
            app.TESTButton.Text = 'TEST';
            app.KListBoxLabel = uilabel(app.UIFigure);
            app.KListBoxLabel.Text = 'K';
            app.KListBox = uilistbox(app.UIFigure);
            app.KListBox.Items = {'1', '3', '5'};
            app.KListBox.Value = '1';
            app.DatasetListBoxLabel = uilabel(app.UIFigure);
            app.DatasetListBoxLabel.Text = 'Dataset';
            app.DatasetListBox = uilistbox(app.UIFigure);
            app.DatasetListBox.Items = {'Yale', 'AT&T Faces'};
            app.DatasetListBox.Value = 'Yale';
            app.DistanceMeasureLabel = uilabel(app.UIFigure);
            app.DistanceMeasureLabel.Text = {'Distance'; 'Measure'};
            app.DistanceMeasureListBox = uilistbox(app.UIFigure);
            app.DistanceMeasureListBox.Items = {'Euclidean', 'Mahalanobis'};
            app.DistanceMeasureListBox.Value = 'Euclidean';
            app.ComponentCountListBoxLabel = uilabel(app.UIFigure);
            app.ComponentCountListBoxLabel.Text = {'Component'; 'Count'};
            app.ComponentCountListBox = uilistbox(app.UIFigure);
            app.ComponentCountListBox.Items = {'10', '30'};
            app.ComponentCountListBox.Value = '10';
            app.ThresholdSpinnerLabel = uilabel(app.UIFigure);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.UIFigure);
            app.ThresholdSpinner.Value = 1e+21;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Each Test requires approximately 50 seconds.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end